classdef app_2048 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel     matlab.ui.container.Panel
        Label     matlab.ui.control.Label
    end

    
    properties (Access = private)
        Grid double                         
        GameOver logical                    
        TileLabels matlab.ui.control.Label  
    end
    
    methods (Access = private)
        function createTileLabels(app)
            % Rozměry panelu (v pixelech)
            panelPos = app.Panel.Position;
            panelW = panelPos(3);
            panelH = panelPos(4);

            padding = 10;  
            n = 4;         
            tileSize = floor((panelW - (n+1)*padding) / n);

            app.TileLabels = matlab.ui.control.Label.empty;

            for row = 1:4
                for col = 1:4
                    % Vypočítat pozici (x,y) uvnitř panelu
                    x = padding + (col-1)*(tileSize + padding);
                    y = panelH - padding - row*(tileSize + padding) + padding;

                    % Vytvořit nový label ze šablony
                    lbl = uilabel(app.Panel);
                    lbl.FontSize = app.Label.FontSize;
                    lbl.FontWeight = app.Label.FontWeight;
                    lbl.HorizontalAlignment = app.Label.HorizontalAlignment;
                    lbl.VerticalAlignment = app.Label.VerticalAlignment;
                    lbl.FontColor = app.Label.FontColor;
                    lbl.FontName = app.Label.FontName;
                    lbl.Position = [x y tileSize tileSize];
                    lbl.BackgroundColor = '#bdac97';
                    lbl.Text = '';
                    
                    idx = (row-1)*4 + col;
                    app.TileLabels(idx) = lbl;
                end
            end
        end
        
        function setStartingTiles(app)
            for i=1:2
                app.spawnNewTile();
            end
            app.updateAllTiles();
        end
        
        function spawnNewTile(app)
            emptyIndices = find(app.Grid == 0);
            if isempty(emptyIndices)
                disp('ran out of grid space, game over');
                return;
            end

            idx = emptyIndices(randi(numel(emptyIndices)));
            [row, col] = ind2sub(size(app.Grid), idx);

            if rand() < 0.1
                value = 4;
            else
                value = 2;
            end

            app.Grid(row, col) = value;
        end

        function bgColor = getValueColor(~, val)
            switch val
                case 0
                    bgColor = '#bdac97';
                case 2
                    bgColor = '#eee4da';
                case 4
                    bgColor = '#ebd8b6';
                case 8
                    bgColor = '#f2ae73';
                case 16
                    bgColor = '#f29667';
                case 32
                    bgColor = '#f27c62';
                case 64
                    bgColor = '#f35f41';
                case 128
                    bgColor = '#eacf76';
                case 256
                    bgColor = '#edcb67';
                case 512
                    bgColor = '#ecc85a';
                case 1024
                    bgColor = '#e7c257';
                case 2048
                    bgColor = '#e8be4e';
                otherwise
                    % Pro hodnoty větší než 2048
                    bgColor = '#b03cc7';
            end
        end

        function updateAllTiles(app)
            for r = 1:4
                for c = 1:4
                    idx = (r - 1)*4 + c;
                    val = app.Grid(r, c);
                    app.updateTileValue(idx, val);
                end
            end
        end
        
        function updateTileValue(app, pos, val)
            if val == 0
                app.TileLabels(pos).Text = "";
            elseif val <= 4
                app.TileLabels(pos).Text = string(val);
                app.TileLabels(pos).FontColor = '#756452';
            else
                app.TileLabels(pos).Text = string(val);
                app.TileLabels(pos).FontColor = '#ffffff';
            end
        
            bgColor = app.getValueColor(val);

            % Set the background color
            app.TileLabels(pos).BackgroundColor = bgColor;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Inicializace herního stavu a UI
            app.Grid = zeros(4,4);
            app.GameOver = false;

            app.createTileLabels();

            % Smazat šablonový label
            app.Label.Visible = 'off';
            app.Label = matlab.ui.control.Label.empty;

            app.setStartingTiles();
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            moved = false;

            function [outLine, changed] = shiftMerge(line)
                nums = line(line ~= 0);
                merged = [];
                i = 1;
                while i <= numel(nums)
                    if i < numel(nums) && nums(i) == nums(i+1)
                        merged(end+1) = nums(i)*2;
                        i = i + 2;
                    else
                        merged(end+1) = nums(i); 
                        i = i + 1;
                    end
                end
                outLine = [merged, zeros(1, 4 - numel(merged))];
                changed = any(outLine ~= line);
            end
       
            switch key
                case 'leftarrow'
                    for r = 1:4
                        [newLine, didChange] = shiftMerge(app.Grid(r, :));
                        if didChange
                            app.Grid(r, :) = newLine;
                            moved = true;
                        end
                    end
    
                case 'rightarrow'
                    for r = 1:4
                        rowRev = fliplr(app.Grid(r, :));
                        [mergedRev, didChange] = shiftMerge(rowRev);
                        newLine = fliplr(mergedRev);
                        if didChange
                            app.Grid(r, :) = newLine;
                            moved = true;
                        end
                    end
    
                case 'uparrow'
                    for c = 1:4
                        colVec = app.Grid(:, c)';
                        [newCol, didChange] = shiftMerge(colVec);
                        if didChange
                            app.Grid(:, c) = newCol';
                            moved = true;
                        end
                    end
    
                case 'downarrow'
                    for c = 1:4
                        colRev = fliplr(app.Grid(:, c)');
                        [mergedRev, didChange] = shiftMerge(colRev);
                        newCol = fliplr(mergedRev)';
                        if didChange
                            app.Grid(:, c) = newCol;
                            moved = true;
                        end
                    end   
                otherwise
                    return 
            end
    
            if moved
                app.spawnNewTile();
                app.updateAllTiles();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.Panel = uipanel(app.UIFigure);
            app.Label = uilabel(app.Panel);
            app.Label.Text = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_2048

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end